classdef ForwardKin2R < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ClearButton            matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        tSlider                matlab.ui.control.Slider
        field_l2               matlab.ui.control.NumericEditField
        field_l1               matlab.ui.control.NumericEditField
        l2Label                matlab.ui.control.Label
        l1Label                matlab.ui.control.Label
        tSliderLabel           matlab.ui.control.Label
        theta2tLabel           matlab.ui.control.Label
        theta120sin3t60Label   matlab.ui.control.Label
        AngledefinitionsLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        l1 % link 1 length
        l2 % link 2 length
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: field_l1
        function field_l1ValueChanged(app, event)
            length1 = app.field_l1.Value;
            app.l1 = length1;
        end

        % Value changed function: field_l2
        function field_l2ValueChanged(app, event)
            length2 = app.field_l2.Value;
            app.l2 = length2;
        end

        % Value changing function: tSlider
        function tSliderValueChanging2(app, event)
            t = event.Value;
            L = [app.l1 app.l2];
            [Xe,Ye,X1,Y1] = RR_ForwardPosKin(L,t);
            plot(app.UIAxes,[0 X1],[0 Y1],'r-');
            hold (app.UIAxes,'on')
            plot(app.UIAxes,[X1 Xe],[Y1 Ye],'b-');
            hold (app.UIAxes,'on')
            plot(app.UIAxes,X1,Y1,'c*');
            hold (app.UIAxes,'on')
            plot(app.UIAxes,Xe,Ye,'k*');
            hold off
            legend(app.UIAxes,'First Link','Second link','Revolute joint','End effector');
            xlabel(app.UIAxes,'X');
            ylabel(app.UIAxes,'Y');
            title(app.UIAxes,'Forward Kinematics of a 2R Serial Chain Robot');
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            clf(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '2R Forward Kinematics')
            app.AngledefinitionsLabel = uilabel(app.UIFigure);
            app.AngledefinitionsLabel.Text = 'Angle definitions';
            app.theta120sin3t60Label = uilabel(app.UIFigure);
            app.theta120sin3t60Label.Text = 'theta1 = 20sin(3t+60)';
            app.theta2tLabel = uilabel(app.UIFigure);
            app.theta2tLabel.Text = 'theta2 = t';
            app.tSliderLabel = uilabel(app.UIFigure);
            app.tSliderLabel.Text = 't';
            app.l1Label = uilabel(app.UIFigure);
            app.l1Label.Text = 'l1';
            app.l2Label = uilabel(app.UIFigure);
            app.l2Label.Text = 'l2';
            app.field_l1 = uieditfield(app.UIFigure, 'numeric');
            app.field_l1.ValueChangedFcn = createCallbackFcn(app, @field_l1ValueChanged, true);
            app.field_l2 = uieditfield(app.UIFigure, 'numeric');
            app.field_l2.ValueChangedFcn = createCallbackFcn(app, @field_l2ValueChanged, true);
            app.tSlider = uislider(app.UIFigure);
            app.tSlider.Limits = [0 360];
            app.tSlider.ValueChangingFcn = createCallbackFcn(app, @tSliderValueChanging2, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '2R_Config.JPG';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForwardKin2R

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end