classdef InverseKin2R < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Switch                 matlab.ui.control.ToggleSwitch
        tSpinner               matlab.ui.control.Spinner
        tSpinnerLabel          matlab.ui.control.Label
        L2EditField            matlab.ui.control.NumericEditField
        L2EditFieldLabel       matlab.ui.control.Label
        L1EditField            matlab.ui.control.NumericEditField
        L1EditFieldLabel       matlab.ui.control.Label
        LinkagesLabel          matlab.ui.control.Label
        EndEffectorLabel       matlab.ui.control.Label
        RadiusEditField        matlab.ui.control.NumericEditField
        RadiusEditFieldLabel   matlab.ui.control.Label
        YcentreEditField       matlab.ui.control.NumericEditField
        YcentreEditFieldLabel  matlab.ui.control.Label
        XcentreEditField       matlab.ui.control.NumericEditField
        XcentreEditFieldLabel  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X_centre % x coordinate centre of the circle
        Y_centre % y coordinate centre of the circle
        Radius   % radius of the circle
        l1       % first linkage length
        l2       % second linkage length
        Config   % crossed/uncrossed
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XcentreEditField
        function XcentreEditFieldValueChanged(app, event)
            x_centre = app.XcentreEditField.Value; %x-coordinate for the circle
            app.X_centre = x_centre;
        end

        % Value changed function: YcentreEditField
        function YcentreEditFieldValueChanged(app, event)
            y_centre = app.YcentreEditField.Value; %y-coordinate for the circle
            app.Y_centre = y_centre;
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            radius = app.RadiusEditField.Value;  %radius for the circle
            app.Radius = radius;
        end

        % Value changed function: L1EditField
        function L1EditFieldValueChanged(app, event)
            L1 = app.L1EditField.Value;
            app.l1 = L1;
        end

        % Value changed function: L2EditField
        function L2EditFieldValueChanged(app, event)
            L2 = app.L2EditField.Value;
            app.l2 = L2;
        end

        % Value changing function: tSpinner
        function tSpinnerValueChanging(app, event)
            T = event.Value;
            xe = ((app.X_centre)+((app.Radius)*cosd(T)));
            ye = ((app.Y_centre)+((app.Radius)*sind(T)));
            L = [app.l1 app.l2]; %saving the lengths as a vector
            A = -2*xe*L(1);
            B = -2*ye*L(1);
            C = ((xe)^2) + ((ye)^2) + ((L(1))^2) - ((L(2))^2);

            if strcmp(app.Config,'Uncrossed')
                theta1 = 2*atan2d(((-2*B)+ sqrt(((2*B)^2)-(4*(((C)^2)-((A)^2))))),(2*(C-A)));
                theta2 = -theta1 + atan2d((ye-(L(1)*sind(theta1))),(xe-(L(1)*cosd(theta1))));
                x1 = L(1)*cosd(theta1); %calculating the first joint x
                y1 = L(1)*sind(theta1); %calculating the first joint y
                Xe_calc = (L(1)*cosd(theta1))+(L(2)*cosd(theta1+theta2)); %back-calculating the end effector coordinates
                Ye_calc = (L(1)*sind(theta1))+(L(2)*sind(theta1+theta2)); %back-calculating the end effector coordinates
                plot(app.UIAxes,[0,x1],[0,y1],'k-');
                axis (app.UIAxes,'equal')
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[x1,Xe_calc],[y1,Ye_calc],'c-');
                hold(app.UIAxes,'on');
                plot(app.UIAxes,Xe_calc,Ye_calc,'ro');
                hold(app.UIAxes,'on');
                plot(app.UIAxes,x1,y1,'bo');
                xlabel(app.UIAxes,'X(cm)');
                ylabel(app.UIAxes,'Y(cm)');
                legend(app.UIAxes,'First Link','Second Link','End Effector ','Second Revolute Joint')
                title(app.UIAxes,'Inverse Kinematic Analysis 2R Serial Chain Robot');
            elseif strcmp(app.Config,'Crossed')
                theta1 = 2*atan2d(((-2*B)- sqrt(((2*B)^2)-(4*(((C)^2)-((A)^2))))),(2*(C-A)));
                theta2 = -theta1 + atan2d((ye-(L(1)*sind(theta1))),(xe-(L(1)*cosd(theta1))));
                x1 = L(1)*cosd(theta1); %calculating the first joint x
                y1 = L(1)*sind(theta1); %calculating the first joint y
                Xe_calc = (L(1)*cosd(theta1))+(L(2)*cosd(theta1+theta2)); %back-calculating the end effector coordinates
                Ye_calc = (L(1)*sind(theta1))+(L(2)*sind(theta1+theta2)); %back-calculating the end effector coordinates
                plot(app.UIAxes,[0,x1],[0,y1],'k-');
                axis (app.UIAxes,'equal')
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[x1,Xe_calc],[y1,Ye_calc],'c-');
                hold(app.UIAxes,'on');
                plot(app.UIAxes,Xe_calc,Ye_calc,'ro');
                hold(app.UIAxes,'on');
                plot(app.UIAxes,x1,y1,'bo');
                xlabel(app.UIAxes,'X(cm)');
                ylabel(app.UIAxes,'Y(cm)');
                legend(app.UIAxes,'First Link','Second Link','End Effector ','Second Revolute Joint')
                title(app.UIAxes,'Inverse Kinematic Analysis 2R Serial Chain Robot');
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            config = app.Switch.Value;
            app.Config = config;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Inverse Kinematic Analysis for a 2R Serial Chain Robot')
            xlabel(app.UIAxes, 'X (cm)')
            ylabel(app.UIAxes, 'Y(cm)')
            zlabel(app.UIAxes, 'Z')
            app.XcentreEditFieldLabel = uilabel(app.UIFigure);
            app.XcentreEditFieldLabel.Text = 'X-centre';
            app.XcentreEditField = uieditfield(app.UIFigure, 'numeric');
            app.XcentreEditField.ValueChangedFcn = createCallbackFcn(app, @XcentreEditFieldValueChanged, true);
            app.YcentreEditFieldLabel = uilabel(app.UIFigure);
            app.YcentreEditFieldLabel.Text = 'Y-centre';
            app.YcentreEditField = uieditfield(app.UIFigure, 'numeric');
            app.YcentreEditField.ValueChangedFcn = createCallbackFcn(app, @YcentreEditFieldValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.EndEffectorLabel = uilabel(app.UIFigure);
            app.EndEffectorLabel.Text = 'End Effector ';
            app.LinkagesLabel = uilabel(app.UIFigure);
            app.LinkagesLabel.Text = 'Linkages';
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditField.ValueChangedFcn = createCallbackFcn(app, @L1EditFieldValueChanged, true);
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditField.ValueChangedFcn = createCallbackFcn(app, @L2EditFieldValueChanged, true);
            app.tSpinnerLabel = uilabel(app.UIFigure);
            app.tSpinnerLabel.Text = 't';
            app.tSpinner = uispinner(app.UIFigure);
            app.tSpinner.ValueChangingFcn = createCallbackFcn(app, @tSpinnerValueChanging, true);
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'Uncrossed', 'Crossed'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Uncrossed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InverseKin2R

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end